classdef sample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FAMOSv100UIFigure       matlab.ui.Figure
        ExportMenu              matlab.ui.container.Menu
        FacultyAttendanceMenu   matlab.ui.container.Menu
        StudentRequestsMenu     matlab.ui.container.Menu
        RoomScheduleMenu        matlab.ui.container.Menu
        AllMenu                 matlab.ui.container.Menu
        Menu                    matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        ConsultationTab         matlab.ui.container.Tab
        GridLayout              matlab.ui.container.GridLayout
        Image                   matlab.ui.control.Image
        FilterDD                matlab.ui.control.DropDown
        WebSrvLbl               matlab.ui.control.Label
        WebAddLbl               matlab.ui.control.Label
        QueueTbl                matlab.ui.control.Table
        MonitoringTab           matlab.ui.container.Tab
        LegendPanel             matlab.ui.container.Panel
        AbsentLamp              matlab.ui.control.Lamp
        AbsentLampLabel         matlab.ui.control.Label
        LeftOfficeLamp          matlab.ui.control.Lamp
        LeftOfficeLampLabel     matlab.ui.control.Label
        EnteredOfficeLamp       matlab.ui.control.Lamp
        EnteredOfficeLampLabel  matlab.ui.control.Label
        MonTbl                  matlab.ui.control.Table
        ScheduleTab             matlab.ui.container.Tab
        Panel_3                 matlab.ui.container.Panel
        AssgnBtn                matlab.ui.control.Button
        MessageTextArea         matlab.ui.control.TextArea
        UseProposedButton       matlab.ui.control.Button
        OptionalLabel           matlab.ui.control.Label
        MessageTextAreaLabel    matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        RoomEF                  matlab.ui.control.EditField
        RoomEditFieldLabel      matlab.ui.control.Label
        EndMinSpn               matlab.ui.control.Spinner
        EndHrSpn                matlab.ui.control.Spinner
        EndTimeSpinnerLabel     matlab.ui.control.Label
        StartMinSpn             matlab.ui.control.Spinner
        StartHrSpn              matlab.ui.control.Spinner
        StartTimeLabel          matlab.ui.control.Label
        DatePicker              matlab.ui.control.DatePicker
        DateLabel               matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        PropSchLbl              matlab.ui.control.Label
        ProposedTimeLabel       matlab.ui.control.Label
        PropDateLbl             matlab.ui.control.Label
        ProposedDateLabel       matlab.ui.control.Label
        RegistrationTab         matlab.ui.container.Tab
        GridLayout3             matlab.ui.container.GridLayout
        DelBtn                  matlab.ui.control.Button
        RegBtn                  matlab.ui.control.Button
        EmailEFld               matlab.ui.control.EditField
        EmailLabel              matlab.ui.control.Label
        LNameEFld               matlab.ui.control.EditField
        NameLabel_6             matlab.ui.control.Label
        MNameEFld               matlab.ui.control.EditField
        NameLabel_5             matlab.ui.control.Label
        FNameEFld               matlab.ui.control.EditField
        NameLabel_4             matlab.ui.control.Label
        mapuaeduphLabel         matlab.ui.control.Label
        ContextMenu             matlab.ui.container.ContextMenu
        RefreshMenu             matlab.ui.container.Menu
    end

    properties (Access = private)
        %% Server Variables
        TCP;
        port;
        config;
        %% Table Variables
        T;
        Tnew;
        %% Communications Objects
        SerialObj;
        SqlObj;
        %% App 
        postreq;
        schedreq;
        idreq;
        pathtoapp;
        commport;
        sendemailbool;
        %% Port Dialog
        UIFigure;
        ConfirmButton;
        COMPortDropDown;
        COMPortLabel;
    end


    methods (Access = private)
        %% Server Functions
        function [TCP,data]=JavaTcpServer(app, action,TCP,data,config)
            global GlobalserverSocket;
            import java.net.Socket
            import java.io.*
            import java.net.ServerSocket
            timeout=300;
            if(nargin<3), data=[]; end

            switch(action)
                case 'initialize'
                    try
                        serverSocket = ServerSocket(data);
                    catch
                        try
                            GlobalserverSocket.close;
                            serverSocket = ServerSocket(data);
                        catch
                            error('JavaTcpServer:initialize', ['Failed to Open Server Port. ' ...
                                'Reasons: "port is still open by Matlab for instance due ' ...
                                'to an previous crash", "Blocked by Firewall application",  ' ...
                                '"Port open in another Application", "No rights to open the port"']);
                        end
                    end

                    serverSocket.setSoTimeout(timeout);
                    TCP.port = data;
                    TCP.serverSocket=serverSocket;
                    GlobalserverSocket=serverSocket;
                case 'accept'
                    tstart = tic;
                    while true
                        t = toc(tstart);
                        try
                            socket = TCP.serverSocket.accept;  
                            break; 
                        catch
                        end
                        drawnow
                        if(t<5), TCP.socket=-1; return, end
                    end


                    TCP.socket = socket;
                    TCP.remoteHost = char(socket.getInetAddress);
                    TCP.outputStream = socket.getOutputStream;
                    TCP.inputStream = socket.getInputStream;
                case 'read'
                    data=zeros(1,1000000,'int8'); tBytes=0;
                    tstart=tic;
                    while(true)
                        nBytes = TCP.inputStream.available;
                        partdata = zeros(1, nBytes, 'int8');
                        for i = 1:nBytes, partdata(i) = DataInputStream(TCP.inputStream).readByte; end
                        data(tBytes+1:tBytes+nBytes) = partdata;
                        tBytes=tBytes+nBytes;
                        % Only exist if the buffer is empty and some request-data
                        % is received, or if the time is larger then 1.5 seconds
                        t=toc(tstart);
                        if(isempty(partdata)&&(t>app.config.mtime1)&&(tBytes>0)), break; end
                        if(isempty(partdata)&&(t>app.config.mtime2)), break; end
                    end
                    data=data(1:tBytes);
                case 'write'
                    if(~isa(data,'int8')), error('TCP:input','Data must be of type int8'); end
                    try
                        DataOutputStream(TCP.outputStream).write(data,0,length(data));
%                         tmp=DataOutputStream(TCP.outputStream);
%                         tmp.write(data,0,length(data));
                    catch
                    end
            		try
            			DataOutputStream(TCP.outputStream).flush;
            		catch
            		end
            	case 'close'
                    TCP.serverSocket.close;
            end
        end

        function handle_figure=stopwindow(app)
            handle_figure=figure;
            set(handle_figure,'Units','Pixels');
            w=get(handle_figure,'Position');
            set(handle_figure,'Position',[w(1) w(2) 200 100]);
            set(handle_figure,'Resize','off')
            uicontrol('Style', 'text','String','Close Window to Shutdown Server','Position', [0 0 200 100]);
            set(handle_figure,'WindowState','minimized')
            handle_figure.WindowState = 'minimized';
        end

        function add_function_paths(app)
            try
                functionname='sample.mlapp';
                functiondir=which(functionname);
                functiondir=functiondir(1:end-length(functionname));
                addpath([functiondir '/functions'])
            catch me
                disp(me.message);
            end
        end

        function text=header2text(app, header)
            text='';
            fn=fieldnames(header);
            for i=1:length(fn)
                name=fn{i}; data=header.(name);
                switch name
                    case 'HTTP'
                        data=['HTTP/1.0 ' data];
                    case 'Date'
                        data=['Date: ' data];
                    case 'Server'
                        data=['Server: ' data];
                    case 'XPoweredBY'
                        data=['X-Powered-By: ' data];
                    case 'SetCookie'
                        data=['Set-Cookie: ' data];
                    case 'Expires'
                        data=['Expires: ' data];
                    case 'CacheControl'
                        data=['Cache-Control: ' data];
                    case 'Pragma'
                        data=['Pragma: ' data];
                    case 'XPingback'
                        data=['X-Pingback: ' data];
                    case 'Connection'
                        data=['Connection: ' data];
                    case 'ContentLength'
                        data=['Content-Length: '  data];
                    case 'ContentType'
                        data=['Content-Type: ' data];
                    case 'LastModified'
                        data=['Last-Modified: ' data];
                    case 'AcceptRanges';
                        data=['Accept-Ranges: ' data];
                    case 'ETag';
                        data=['ETag: ' data];
                    case'KeepAlive';
                        data=['Keep-Alive: ' data];
                    otherwise
                        warning('header fieldname wrong');
                        continue;
                end
                text=[text data '\n'];
            end
            text=[text '\n'];
            text=sprintf(text);
        end

        function header = text2header(app, requestdata,config)
            request_text=char(requestdata);
            request_lines = regexp(request_text, '\r\n+', 'split');
            request_words = regexp(request_lines, '\s+', 'split');
            for i=1:length(request_lines)
                line=request_lines{i};
                if(isempty(line)), break; end
                type=request_words{i}{1};
                switch(lower(type))
                    case 'get'
                        header.Get.Filename=request_words{i}{2};
                        header.Get.Protocol=request_words{i}{3};
                    case 'post'
                        header.Post.Filename=request_words{i}{2};
                        header.Post.Protocol=request_words{i}{3};
                    case 'host:'
                        header.Host=rmvp(app, line(7:end));
                    case 'user-agent:'
                        header.UserAgent=rmvp(app, line(13:end));
                    case 'accept:'
                        header.Accept=rmvp(app, line(9:end));
                    case 'accept-language:'
                        header.AcceptLanguage=rmvp(app, line(18:end));
                    case 'accept-encoding:'
                        header.AcceptEncoding=rmvp(app, line(18:end));
                    case 'accept-charset:'
                        header.AcceptCharset=rmvp(app, line(17:end));
                    case 'keep-alive:'
                        header.KeepAlive=rmvp(app, line(13:end));
                    case 'connection:'
                        header.Connection=rmvp(app, line(13:end));
                    case 'content-length:'
                        header.ContentLength=rmvp(app, line(17:end));
                    case 'content-type:'
                        %lines=rmvp(line(15:end));
                        switch rmvp(app, request_words{i}{2})
                            case {'application/x-www-form-urlencoded','application/x-www-form-urlencoded;'}
                                header.ContentType.Type='application/x-www-form-urlencoded';
                                header.ContentType.Boundary='&';
                            case {'multipart/form-data','multipart/form-data;'}
                                header.ContentType.Type='multipart/form-data';
                                str=request_words{i}{3};
                                header.ContentType.Boundary=str(10:end);
                            otherwise
                                disp('unknown')
                        end
                    otherwise
                end
            end
            header.Content=struct;
            if(isfield(header,'ContentLength'))
                cl=str2double(header.ContentLength);
                str=request_text(end-cl+1:end);
                data=requestdata(end-cl+1:end);
                if(~isfield(header,'ContentType'))
                    header.ContentType.Type=''; header.ContentType.Boundary='&';
                end
                switch (header.ContentType.Type)
                    case {'application/x-www-form-urlencoded',''}
                        str=rmvp(app, str);
                        words = regexp(str, '&', 'split');
                        for i=1:length(words)
                            words2 = regexp(words{i}, '=', 'split');
                            header.Content.(words2{1})=words2{2};
                        end
                    case 'multipart/form-data'
                        pos=strfind(str,header.ContentType.Boundary);
                        while((pos(1)>1)&&(str(pos(1)-1)=='-'))
                            header.ContentType.Boundary=['-' header.ContentType.Boundary];
                            pos=strfind(str,header.ContentType.Boundary);
                        end

                        for i=1:(length(pos)-1)
                            pstart=pos(i)+length(header.ContentType.Boundary);
                            pend=pos(i+1)-3; % Remove "13 10" End-line characters
                            subrequestdata=data(pstart:pend);
                            subdata= multipart2struct(app, subrequestdata,config);
                            header.Content.(subdata.Name).Filename=subdata.Filename;
                            header.Content.(subdata.Name).ContentType=subdata.ContentType;
                            header.Content.(subdata.Name).ContentData=subdata.ContentData;
                        end
                    otherwise
                        disp('unknown')
                end
            end
        end

        function header=make_bin_http_header(app, html,found)
            if(found)
                header.HTTP='200 OK';
            else
                header.HTTP='404 Not Found';
            end
            header.Date='Tue, 12 Oct 2010 09:19:05 GMT';
            header.Server='Matlab Webserver';
            header.LastModified='Last-Modified: Thu, 21 Jun 2007 14:56:37 GMT';
            header.AcceptRanges='Accept-Ranges: bytes';
            header.ContentLength=num2str(length(html));
            header.ETag='"948921-15ae-c0dbf340"';
            %header.KeepAlive='Keep-Alive: timeout=15, max=100';
            header.ContentType='application/octet-stream';
        end

        function header=make_html_http_header(app, html,found)
            if(found)
                header.HTTP='200 OK';
            else
                header.HTTP='404 Not Found';
            end
            header.Date='Tue, 12 Oct 2010 09:19:05 GMT';
            header.Server='Matlab Webserver';
            header.XPoweredBY=['Matlab' version];
            header.SetCookie='SESSID=5322082bf473207961031e3df1f45a22; path=/';
            header.Expires='Thu, 19 Nov 1980 08:00:00 GMT';
            header.CacheControl='no-store, no-cache, must-revalidate, post-check=0, pre-check=0';
            header.Pragma='no-cache';
            header.Connection='close';
            header.ContentLength=num2str(length(html));
            header.ContentType='text/html; charset=UTF-8';
        end

        function header=make_image_http_header(app, html,found)
            if(found)
                header.HTTP='200 OK';
            else
                header.HTTP='404 Not Found';
            end
            header.Date='Tue, 12 Oct 2010 09:19:05 GMT';
            header.Server='Matlab Webserver';
            header.LastModified='Last-Modified: Thu, 21 Jun 2007 14:56:37 GMT';
            header.AcceptRanges='Accept-Ranges: bytes';
            header.ContentLength=num2str(length(html));
            header.ETag='"948921-15ae-c0dbf340"';
            %header.KeepAlive='Keep-Alive: timeout=15, max=100';
            header.ContentType='image/png';
        end

        function subdata=multipart2struct(app, subrequestdata,config)
            request_text=char(subrequestdata);
            request_lines = regexp(request_text, '\n+', 'split');
            request_words = regexp(request_lines, '\s+', 'split');
            
            i=1;
            subdata=struct;
            subdata.Name='';
            subdata.Filename='';
            subdata.ContentType='';
            subdata.ContentData='';
            while(true)
                i=i+1; if((i>length(request_lines))||(uint8(request_lines{i}(1)==13))), break; end
                line=request_lines{i};
                type=request_words{i}{1};
                switch(type)
                    case 'Content-Disposition:'
                        for j=3:length(request_words{i})
                            line_words=regexp(request_words{i}{j}, '"', 'split');
                            switch(line_words{1})
                                case 'name='
                                    subdata.Name=line_words{2};
                                case 'filename='
                                    subdata.Filename=line_words{2};
                            end
                        end
                    case 'Content-Type:'
                         subdata.ContentType=rmvp(app, line(15:end));
                end
            end
            w=find(subrequestdata==10);
            switch(subdata.ContentType) 
                case ''
                    subdata.ContentData=char(subrequestdata(w(i)+1:end));
                otherwise
                    subdata.ContentData=subrequestdata(w(i)+1:end);
                    [pathstr,name,ext] = fileparts(subdata.Filename);
                    filename=['/' char(round(rand(1,32)*9)+48)];
                    fullfilename=[config.temp_folder filename ext];
                    fid = fopen(fullfilename,'w'); fwrite(fid,subdata.ContentData,'int8'); fclose(fid);
                    subdata.Filename=fullfilename;
            end
        end

        function str=rmvp(app, str)
            while((str(end)==' ') ||(uint8(str(end))==13)), str=str(1:end-1); end
        end

        function html=formshow(app, headers)
            html = '<!DOCTYPE html>';
            html=[html '<html><head><title>Meeting Request Submitted</title><LINK rel="icon" type="image/x-icon" href="favicon.ico"><STYLE>h2 {text-align: center; font-family: Gill Sans, sans-serif;} div {text-align: center; font-family: Gill Sans, sans-serif;} label {display: inline-block; width: 120px; text-align: right; font-family: Gill Sans, sans-serif;} table, td {border: 1px solid black; border-collapse: collapse;} table.center {margin-left: auto; margin-right: auto;} </STYLE></head>'];
            html=[html char("<body><br><br><h2>Meeting Request Submitted!</h2><div>Please wait for your professor's acknowledgement.</div><div>The final meeting details will be sent to your email.</div>")];
            html=[html '<br><div>Submission summary:</div><br>'];
            html=[html '<div><table class="center">'];
            ContentForm=headers.Content;
            fn=fieldnames(ContentForm);
            for i=1:length(fn)
                switch fn{i}
                    case 'name'
                        lab = 'Student Name: ';
                    case 'number'
                        lab = 'Student Number: ';
                    case 'email'
                        lab = 'Email Address: ';
                    case 'purpose'
                        lab = 'Purpose of Request: ';
                    case 'propdate'
                        lab = 'Proposed Date: ';
                    case 'proptimestart'
                        lab = 'Proposed Starting Time: ';
                    case 'proptimeend'
                        lab = 'Proposed Ending Time: ';
                    case 'faculty'
                        lab = 'Professor: ';
                    case 'subjsec'
                        lab = 'Course/Section: ';
                    case 'stdmsg'
                        lab = 'Message: ';
                end
            	html=[html '<tbody><tr><td>' lab '</td><td>' ContentForm.(fn{i}) '</td></tbody></tr>'];
            end
            html=[html '</table></div><br><br><div><a href="form.php">Back to Previous Page</a></div><br></body></html>'];
        end

        %% My Functions
        function assignport(app, dur)
            app.FAMOSv100UIFigure.Visible = "off";
            comports = serialportlist("available");
            if length(comports) > 1
                app.UIFigure = uifigure('Visible', 'off');
                app.UIFigure.Position = [100 100 267 130];
                app.UIFigure.Name = 'Select COM Port';

                % Create COMPortLabel
                app.COMPortLabel = uilabel(app.UIFigure);
                app.COMPortLabel.HorizontalAlignment = 'right';
                app.COMPortLabel.Position = [43 81 66 22];
                app.COMPortLabel.Text = 'COM Port: ';

                % Create COMPortDropDown
                app.COMPortDropDown = uidropdown(app.UIFigure);
                app.COMPortDropDown.Items = ['---Port List---' comports];
                app.COMPortDropDown.Position = [124 81 100 22];
                app.COMPortDropDown.Value = '---Port List---';

                % Create ConfirmButton
                app.ConfirmButton = uibutton(app.UIFigure, 'push', 'ButtonPushedFcn', @(btn,event) ConfirmButtonPushed(app, event));
                app.ConfirmButton.Position = [87 33 100 23];
                app.ConfirmButton.Text = 'Confirm';
                app.UIFigure.Visible = 'on';
                uiwait(app.UIFigure)
            else
                app.commport = comports;
            end
            app.SerialObj = serialport(app.commport, 9600, 'Timeout', dur);
            flush(app.SerialObj);
            app.FAMOSv100UIFigure.Visible = "on";


            function ConfirmButtonPushed(app, event)
                app.commport = app.COMPortDropDown.Value;
                delete(app.UIFigure)
            end
        end
        
        function setupapp(app, dur)
            app.port = 1029;
            app.sendemailbool = false;
            userProfile = getenv('USERPROFILE');
            app.pathtoapp = [userProfile '\Desktop\FAMOS'];
            app.DatePicker.Value = datetime(now(), 'ConvertFrom', 'datenum', 'Format', 'dd-MMM-yyyy');
            app.DatePicker.Limits = [datetime(now(), 'ConvertFrom', 'datenum', 'Format', 'dd-MMM-yyyy') datetime([9999 12 31])];
            app.config = struct('www_folder', app.pathtoapp,'temp_folder','www/temp','verbose',true,'defaultfile','/form.php','mtime1',0.3,'mtime2',0.9);

            try %% Exit if Arduino is not detected
                if isempty(app.commport)
                    assignport(app, dur);
                else
                    app.SerialObj = serialport(app.commport, 9600, 'Timeout', dur);
                end
                flush(app.SerialObj)
            catch
                fig = uifigure('Position',[408 285 384 190]);
                fig.CloseRequestFcn = @(src,event)my_closereq(src);
                uialert(fig, 'Arduino Board not setup properly. Please connect the board and restart the application.', 'Unexpected Error', ...
                    'modal', true, ...
                    'CloseFcn', @(src, event)my_closereq(src))
                close(app.SqlObj)
                delete(app)
                return
            end

            try % Exit if Database is not properly setup
                vendor = "MySQL";
                opts = databaseConnectionOptions("jdbc",vendor);
                opts = setoptions(opts, ...
                    'DataSourceName',"trodes", ...
                    'JDBCDriverLocation', fullfile(app.pathtoapp, "mysql-connector-java-8.0.30.jar"), ...
                    'DatabaseName',"trodes", ...
                    'Server',"localhost", ...
                    'PortNumber',3306);
                saveAsDataSource(opts);
                app.SqlObj = database('trodes','trodesuser','trodespass');
            catch ME
                fig = uifigure('Position',[408 285 384 190]);
                fig.CloseRequestFcn = @(src,event)my_closereq(src);
%                 uialert(fig, 'Database not setup properly. Please install XAMPP in C:\ and setup the database.', 'Missing Database', ...
%                     'modal', true, ...
%                     'CloseFcn', @(src, event)my_closereq(src))
                uialert(fig, [ME.message 'Identifier: ' ME.identifier], 'Missing Database', ...
                    'modal', true, ...
                    'CloseFcn', @(src, event)my_closereq(src))

                delete(app)
                return
            end

            try
                app.TCP=JavaTcpServer(app, 'initialize',[],app.port,app.config);
            end
            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
            end
        end

        function tap(app)
            while true
                try
                    if ~isvalid(app.SerialObj)                                  %Check if close conn
                        break
                    end
                    res = read(app.SerialObj, 8, 'string');

                    if ~isempty(res)
                        if strlength(res) == 8
                            %% Upload to SQL
                            write(app.SerialObj,'a','string');
                            curr = datetime('now');
                            currdate = datestr(curr, 'yyyy-mm-dd');
                            currtime = datestr(curr, 'hh:MM:ss');
                            %Search UID from database
                            % Check if unregistered
                            query = sprintf('SELECT uid FROM faculty WHERE uid = ''%s'';', res);
                            data = fetch(app.SqlObj,query);
                            if isempty(data)
                                app.TabGroup.Visible = false;
                                fig = uifigure('Position',[408 285 384 190]);
                                fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                uialert(fig,'Please register your card in the Registration tab.','Unregistered Card Detected',...
                                    'Icon','error', ...
                                    'modal', true, ...
                                    'CloseFcn', @(src, event)my_closereq(src));
                                continue
                            end
                            query = sprintf('SELECT * FROM trodes.attendance WHERE currdate = ''%s'' and cardid = ''%s'' and logout = ''-'';', currdate, res);
                            data = fetch(app.SqlObj,query);
                            if isempty(data) %First Time
                                query = sprintf('INSERT INTO attendance (cardid, login, logout) VALUES (''%s'', ''%s'', ''-'');', res, currtime);
                                execute(app.SqlObj,query);
                                tstart = tic;
                                query = sprintf('SELECT lname FROM faculty WHERE uid = ''%s'';', res);
                                fac = fetch(app.SqlObj, query);
                                while toc(tstart) <2
                                    fig = uifigure('Position',[408 285 384 190]);
                                    fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                    uialert(fig,sprintf('Hello, Prof. %s!', fac.lname{1}),'Welcome!',...
                                        'Icon','success', ...
                                        'modal', true, ...
                                        'CloseFcn', @(src, event)my_closereq(src));
                                    pause(2)
                                end
                            else            %Update
                                query = sprintf('UPDATE attendance SET logout = ''%s'' WHERE cardid = ''%s'' and logout = ''-'';', currtime, res);
                                execute(app.SqlObj,query);
                                write(app.SerialObj,'a',"uint8")
                                tstart = tic;
                                query = sprintf('SELECT lname FROM faculty WHERE uid = ''%s'';', res);
                                fac = fetch(app.SqlObj, query);
                                while toc(tstart) <2
                                    fig = uifigure('Position',[408 285 384 190]);
                                    fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                    uialert(fig,sprintf('Ingat po, Prof. %s!', fac.lname{1}),'Welcome!',...
                                        'Icon','success', ...
                                        'modal', true, ...
                                        'CloseFcn', @(src, event)my_closereq(src));
                                    pause(2)
                                end
                            end
                            delete(fig)
                            showatt(app)
                        end
                    else
                        %% webserver
                        try
                            app.TCP=JavaTcpServer(app, 'accept',app.TCP,[],app.config);
                        catch
                            break
                        end

                        % If socket is -1, the user has close the "Close Window"
                        if(app.TCP.socket==-1), continue, end

                        % Read the data form the browser as an byte array of type int8

                        [app.TCP,requestdata]=JavaTcpServer(app, 'read',app.TCP,[],app.config);
                        if(isempty(requestdata))
                            continue;
                        end

                        % Convert the Header text to a struct
                        request = text2header(app, requestdata,app.config);

                        % The filename asked by the browser
                        if(isfield(request,'Get'))
                            filename=request.Get.Filename;
                        elseif(isfield(request,'Post'))
                            filename=request.Post.Filename;                         %upload to sql
                            app.postreq = request.Content;
                            app.postreq = html2sql(app, app.SqlObj, app.postreq);
                        else
                            warning('Unknown Type of Request');
                            continue
                        end

                        % If no filename, use default
                        if(strcmp(filename,'/')), filename = app.config.defaultfile; end

                        % Make the full filename inluding path
                        fullfilename=[app.config.www_folder filename];
                            [pathstr,name,ext] = fileparts(fullfilename);


                        % Check if file asked by the browser can be opened
                        fid = fopen(fullfilename, 'r+');
                        if(fid<0)
                            filename='/404.html'; found=false;
                            fullfilename=[app.config.www_folder filename];
                        else
                            found=true; fclose(fid);
                        end

                        % Based on the extention asked, read a local file and parse it.
                        % or execute matlab code, which generates the file
                        switch(ext)
                            case {'.html'}
                                addpath(pathstr)
                                try
                                    request.Content = app.postreq;
                                catch
                                end
                                try
                                    html = formshow(app, request);
                                catch ME
                                    html=['<html><body><font color="#FF0000">Error in file : ' name ...
                                        '.m</font><br><br><font color="#990000"> The file returned the following error: <br>' ...
                                        ME.message '</font></body></html>'];
                                end
                                rmpath(pathstr)
                                header=make_html_http_header(app, html,found);
                                response=header2text(app, header);
                            case {'.php'}
                                txtf = fileread(fullfile(pathstr, [name 'orig' ext]));
                                index = strfind(txtf, 'value="">-----Select-----</option>');
                                txtf(index:index+length('value="">-----Select-----</option>'));
                                txta = txtf(1:index);
                                txtb = txtf(index+41:end);
                                query = 'SELECT fullname FROM faculty';
                                data = fetch(app.SqlObj, query);
                                line = '';
                                for i = 1:height(data)
                                    line = strcat(line,'<OPTION value="', data.fullname{i}, '">', data.fullname{i}, '</OPTION>');
                                end
                                txtfull = strcat(txta, txtf(index-1:index+41),line, txtb);
                                fid = fopen(fullfilename, 'wt');
                                fprintf(fid, txtfull);
                                fclose(fid);
                                fid = fopen(fullfilename, 'r');
                                html = fread(fid, inf, 'int8')';
                                fclose(fid);
                                header=make_html_http_header(app, html,found);
                                response=header2text(app, header);
                            case {'.jpg','.png','.gif','.ico'}
                                fid = fopen(fullfilename, 'r');
                                html = fread(fid, inf, 'int8')';
                                fclose(fid);
                                header=make_image_http_header(app, html,found);
                                response=header2text(app, header);
                            otherwise
                                fid = fopen(fullfilename, 'r');
                                html = fread(fid, inf, 'int8')';
                                fclose(fid);
                                header=make_bin_http_header(app, html,found);
                                response=header2text(app, header);
                        end
                        % Give the generated HTML or binary code back to the browser
                        JavaTcpServer(app, 'write',app.TCP,int8(response),app.config);
                        JavaTcpServer(app, 'write',app.TCP,int8(html),app.config);
                        if app.sendemailbool == true
                            profemail(app, app.postreq)
                            app.sendemailbool = false;
                        end
                    end
                catch
%                     fig = uifigure('Position',[408 285 384 190]);
%                     fig.CloseRequestFcn = @(src,event)my_closereq(src);
%                     uialert(fig, 'Arduino Board not detected. Please connect the board and restart the application.', 'Unexpected Error', ...
%                         'modal', true, ...
%                         'CloseFcn', @(src, event)my_closereq(src))
%                     close(app.SqlObj)
%                     delete(app)
                    return
                end
            end
            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
            end
        end

        function reg(app, fname, mname, lname, email)
            fullname = [lname ', ' fname ' ' mname];
            while true
                try
                    if ~isvalid(app.SerialObj)                                  %Check if close conn
                        break
                    end
                    app.TabGroup.Visible = false;
                    fig = uifigure('Position',[408 285 384 190]);
                    fig.CloseRequestFcn = @(src,event)my_closereq(src);
                    uialert(fig, 'You may now tap your RFID.', 'Tap now', ...
                        'Icon', 'info', ...
                        'CloseFcn', @(src, event)my_closereq(src));
                    res = [];
                    flush(app.SerialObj)
                    tstart = tic;
                    while isempty(res) && toc(tstart) < 5
                        res = read(app.SerialObj, 8, 'string');
                        app.TabGroup.Visible = true;
                    end
                    if ~isempty(res)
                        if strlength(res) ~= 2
                            delete(fig)
                            write(app.SerialObj,'a','string');
                            %% Upload to SQL
                            %Search UID
                            query =["SELECT * FROM trodes.faculty WHERE uid = '" res "';"];
                            query = strcat(query(1),query(2), query(3));
                            data = fetch(app.SqlObj,query);
                            if isempty(data) %First Time
                                query = sprintf('INSERT INTO faculty (uid, fullname, fname, mname, lname, email) VALUES (''%s'', ''%s'', ''%s'', ''%s'', ''%s'', ''%s'');', ...
                                    res, fullname, fname, mname, lname, email);
                                execute(app.SqlObj,query);
                                app.FNameEFld.Value = '';
                                app.MNameEFld.Value = '';
                                app.LNameEFld.Value = '';
                                app.EmailEFld.Value = '';
                                fig = uifigure('Position',[408 285 384 190]);
                                fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                uialert(fig,'Database Updated','Operation Success',...
                                    'Icon','success', ...
                                    'modal', true, ...
                                    'CloseFcn', @(src, event)my_closereq(src));
                                break;
                                %LCD Display Success
                            else            %Update
                                %UI Warn ID is already registered, do you
                                %want to update?
                                fig = uifigure('Position',[408 285 384 190]);
                                fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                selection = uiconfirm(fig, 'ID is already registered. Update registry?', 'Record Exists', ...
                                    'Options', {'Update', 'Cancel'}, ...
                                    'CancelOption', 2, ...
                                    'Icon', 'question', ...
                                    'CloseFcn', @(src, event)my_closereq(src));
                                switch selection
                                    case 'Update'
                                        query = sprintf('UPDATE faculty SET fullname = ''%s'', fname = ''%s'', mname = ''%s'', lname = ''%s'', email = ''%s'' WHERE uid = ''%s'';', ...
                                            fullname, fname, mname, lname, email, res);
                                        execute(app.SqlObj,query);
                                        fig = uifigure('Position',[408 285 384 190]);
                                        fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                        uialert(fig,'Database Updated','Operation Success',...
                                            'Icon','success', ...
                                            'modal', true, ...
                                            'CloseFcn', @(src, event)my_closereq(src));
                                    case 'Cancel'
                                        %clear txt fields
                                end
                                break;
                            end
                        end
                    else
                        try
                            delete(fig)
                        end
                        fig = uifigure('Position',[408 285 384 190]);
                        fig.CloseRequestFcn = @(src,event)my_closereq(src);
                        uialert(fig, 'RFID Timeout.', 'Error', ...
                            'Icon', 'error', ...
                            'CloseFcn', @(src, event)my_closereq(src));
                        return
                    end
                catch
                    return
                end
            end

            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
            end
        end

        function del(app)
            while true
                try
                    if ~isvalid(app.SerialObj)                                  %Check if close conn
                        break
                    end
                    app.TabGroup.Visible = false;
                    fig = uifigure('Position',[408 285 384 190]);
                    fig.CloseRequestFcn = @(src,event)my_closereq(src);
                    uialert(fig, 'You may now tap your RFID.', 'Tap now', ...
                        'Icon', 'info', ...
                        'CloseFcn', @(src, event)my_closereq(src));
                    res = [];
                    flush(app.SerialObj)
                    tstart = tic;
                    while isempty(res) && toc(tstart) < 5
                        res = read(app.SerialObj, 8, 'string');
                        app.TabGroup.Visible = true;
                    end
                    if ~isempty(res)
                        if strlength(res) ~= 2 %RFID Detected
                            try
                                delete(fig)
                            end
                            write(app.SerialObj,'a','string');
                            query = sprintf('SELECT * FROM trodes.faculty WHERE uid = ''%s'';', res);
                            data = fetch(app.SqlObj,query);
                            if ~isempty(data)
                                fig = uifigure('Position',[408 285 384 190]);
                                fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                selection = uiconfirm(fig, strcat("Are you sure you want to delete the record of '", data.fullname{1}, "'?"), 'Confirm Delete', ...
                                    'Options', {'Delete', 'Cancel'}, ...
                                    'CancelOption', 2, ...
                                    'Icon', 'question', ...
                                    'CloseFcn', @(src, event)my_closereq(src));
                                switch selection
                                    case 'Delete'
                                        query = sprintf('DELETE FROM faculty WHERE uid = ''%s'';', res);
                                        execute(app.SqlObj,query);
                                        disp('del success')
                                        fig = uifigure('Position',[408 285 384 190]);
                                        fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                        uialert(fig,'Database Updated','Operation Success',...
                                            'Icon','success', ...
                                            'modal', true, ...
                                            'CloseFcn', @(src, event)my_closereq(src));
                                    case 'Cancel'
                                        return;
                                end
                                break;
                            else
                                fig = uifigure('Position',[408 285 384 190]);
                                fig.CloseRequestFcn = @(src,event)my_closereq(src);
                                uialert(fig,'Record not Found','Database Error',...
                                    'Icon','error', ...
                                    'modal', true, ...
                                    'CloseFcn', @(src, event)my_closereq(src));
                                break;
                            end
                        end
                        break;
                    else
                        try
                            delete(fig)
                        end
                        fig = uifigure('Position',[408 285 384 190]);
                        fig.CloseRequestFcn = @(src,event)my_closereq(src);
                        uialert(fig, 'RFID Timeout.', 'Error', ...
                            'Icon', 'error', ...
                            'CloseFcn', @(src, event)my_closereq(src));
                        return
                    end
                catch
                    return
                end
            end

            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
            end
        end

        function newstruct = html2sql(app, obj, sqlstruct)
            nm = sqlstruct.name;
            nb = sqlstruct.number;
            email = sqlstruct.email;
            purp = sqlstruct.purpose;
            dt = sqlstruct.propdate;
            tmst = sqlstruct.proptimestart;
            tmed = sqlstruct.proptimeend;
            fac = sqlstruct.faculty;
            subjsec = sqlstruct.subjsec;
            stdmsg = sqlstruct.stdmsg;

            % Clean Data
            id = strfind(nm, '+');          %name
            nm(id) = ' ';
            id = strfind(nm, '%2C');
            nm(id)= ',';
            nm(id+1:id+2) = '';

            id = strfind(email, '%40');     %email
            email(id)= '@';
            email(id+1:id+2) = '';
            
            id = strfind(purp, '+');       %purp
            purp(id) = ' ';

            id = strfind(tmst, '%3A');     %timestart
            tmst(id)= ':';
            tmst(id+1:id+2) = '';

            id = strfind(tmed, '%3A');     %timeend
            tmed(id)= ':';
            tmed(id+1:id+2) = '';

            id = strfind(fac, '+');       %fac
            fac(id) = ' ';
            id = strfind(fac, '%2C');
            fac(id)= ',';
            fac(id+1:id+2) = '';

            %clean subjsec
            id = strfind(subjsec, '%2F');     %subjsec  %/
            subjsec(id)= '/';
            subjsec(id+1:id+2) = '';

            %clean stdmsg
            id = strfind(stdmsg, '+');      %stdmsg
            stdmsg(id) = ' ';


            newstruct.name = nm;
            newstruct.number = nb;
            newstruct.subjsec = subjsec;
            newstruct.email = email;
            newstruct.purpose = purp;
            newstruct.propdate = dt;
            newstruct.proptimestart = tmst;
            newstruct.proptimeend = tmed;
            newstruct.faculty = fac;
            newstruct.stdmsg = stdmsg;

            query = sprintf('INSERT INTO studreq (stud_name, stud_num, email, purpose, fac, subjectsection, prop_date, prop_time_start, prop_time_end, stdmessage ,message) VALUES (''%s'', ''%s'', ''%s'', ''%s'', ''%s'', ''%s'', ''%s'', ''%s'', ''%s'', ''%s'', '''');', ...
                nm, nb, email, purp, fac, subjsec, dt, tmst, tmed, stdmsg);
            execute(obj, query);
            showtab(app, obj);
            %Email prof
            app.sendemailbool = true;
%             profemail(app, newstruct)
            
        end

        function emailsend(app, obj, id)
            info = fetch(obj, char(['SELECT * FROM studreq WHERE id = ' num2str(id)]));

            addr = 'trodes1029@gmail.com';
            pass = 'jlzmghpolnteldai';
            setpref('Internet', 'E_mail', addr);
            setpref('Internet', 'SMTP_Server', 'smtp.gmail.com');
            setpref('Internet', 'SMTP_Username', addr);
            setpref('Internet', 'SMTP_Password', pass);
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth', 'true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port', '465');
            tmp = info.fac{1};
            facsur = [];
            for i = 1:length(info.fac{1})
                if ~strcmp(tmp(i), ',')
                    facsur = [facsur tmp(i)];
                else
                    break
                end
            end
            fin_time_start_str = datestr(info.fin_time_start{1}, 'HH:MM AM');
            fin_time_end_str = datestr(info.fin_time_end{1}, 'HH:MM AM');
            subject = sprintf('Finalized Schedule for %s with Prof. %s', info.purpose{1}, facsur);
            if strcmp(info.room{1}, 'TBA')
                info.room{1} = 'EECE Consultation Room';
            end
            if ~isempty(info.message{1})
                a = sprintf('Good day, %s.\n\n\tProf. %s has acknowledged your meeting request.\n\nRoom Details: %s\nDate: %s\nSchedule: %s - %s\n\nMessage from your professor:\n\n\t"%s"\n\n', ...
                info.stud_name{1}, facsur, info.room{1}, info.fin_date{1}, fin_time_start_str, fin_time_end_str, info.message{1});
            else
                a = sprintf('Good day, %s.\n\n\tProf. %s has acknowledged your meeting request.\n\nRoom Details: %s\nDate: %s\nSchedule: %s - %s\n\n', ...
                info.stud_name{1}, facsur, info.room{1}, info.fin_date{1}, fin_time_start_str, fin_time_end_str);
            end
            b = sprintf('Kindly take note of your scheduled appointment and come on time.\n\n\n- School of Electrical, Electronics, and Computer Engineering. \n\n\n\n(This message is autogenerated, do not reply.)');
            msg = [a b];
            sendmail(info.email{:}, subject, msg, '');           
        end

        function profemail(app, struct)
            %% Setup
            tmp = struct.faculty;
            facemail = fetch(app.SqlObj, sprintf('SELECT email FROM faculty WHERE fullname = ''%s''', struct.faculty));
            facsur = fetch(app.SqlObj, sprintf('SELECT lname FROM faculty WHERE fullname = ''%s''', struct.faculty));

            %% Content
            subject = 'New Meeting Request';
            a = sprintf('Good day, Prof. %s. \n\n%s, from %s, has requested', facsur.lname{1}, struct.name, struct.subjsec);
            switch struct.purpose
                case {'Make-up Exam','Research Consultation'}
                    b = sprintf(' a meeting for a %s.\n',struct.purpose);
                case {'Grade Verification'}
                    b = sprintf(' a meeting for %s.\n',struct.purpose);
                case 'Inquiry'
                    b = sprintf(' a meeting for an %s.\n',struct.purpose);
                otherwise
                    b = sprintf(' for a meeting.\n');
            end
            d = sprintf('For more details, kindly check the Faculty Monitoring Application.\n\n- School of Electrical, Electronics, and Computer Engineering. \n\n\n\n(This message is autogenerated, do not reply.)');

            if isempty(struct.stdmsg)
                msg = [a b d];
            else
                c = sprintf('\nMessage from the student:\n\n\t"%s"\n\n', struct.stdmsg);
                msg = [a b c d];
            end
            
            %% Email Settings
            addr = 'trodes1029@gmail.com';
            pass = 'jlzmghpolnteldai';
            setpref('Internet', 'E_mail', addr);
            setpref('Internet', 'SMTP_Server', 'smtp.gmail.com');
            setpref('Internet', 'SMTP_Username', addr);
            setpref('Internet', 'SMTP_Password', pass);
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth', 'true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port', '465');
            sendmail(sprintf('%s@mymail.mapua.edu.ph',facemail.email{:}), subject, msg, ''); 
%             sendmail(sprintf('%s@.mapua.edu.ph',facemail.email{:}), subject, msg, '')
        end

        function showatt(app)
            count = 1;
            montab = table;
            today = datestr(datetime(now(), 'ConvertFrom', 'datenum', 'Format', 'dd-MMM-yyyy'), 'yyyy-mm-dd');
            query = sprintf('SELECT attendance.id, faculty.fullname, attendance.logout FROM faculty INNER JOIN attendance ON faculty.uid=attendance.cardid WHERE attendance.currdate = ''%s'' ORDER BY faculty.fullname ASC, attendance.logout DESC;', today);
            data = fetch(app.SqlObj, query);
            if isempty(data)
                query = sprintf('SELECT faculty.fullname FROM faculty where faculty.fullname NOT IN (SELECT DISTINCT faculty.fullname FROM faculty INNER JOIN attendance ON faculty.uid=attendance.cardid WHERE attendance.currdate);');
                absentlist = fetch(app.SqlObj, query);
                blankcol = strings(height(absentlist), 1);
                absentlist.txt = blankcol;
                app.MonTbl.Data = absentlist(:,:);
                return
            end
            faculty = unique(data.fullname);
            for i = 1:length(faculty)
                while true
                    try
                        if strcmp(faculty{i}, data.fullname{count}) == 1
                            temp = data(count, :);
                            count = count + 1;
                        else
                            break
                        end
                    catch
                        break
                    end
                end
                montab = [montab; temp];
            end
            montab_in = table;
            montab_out = table;
            for i = 1:height(montab.logout)
                if strcmp(montab.logout{i}, '-')
                    montab_in = [montab_in; montab(i,:)];
                else
                    montab_out = [montab_out; montab(i,:)];
                end
            end
            montab = [montab_in; montab_out];

            newdata =[];
            for i = 1:height(montab.logout)
                if strcmp(montab.logout{i}, '-')
                    newdata = [newdata; 'g'];
                else
                    newdata = [newdata; 'r'];
                end
            end
            montab.color = newdata;

            
            blankcol = strings(height(montab), 1);
            montab.txt = blankcol;
            app.MonTbl.Data = montab(:,[2 4]);
            [row_r,col_r] = find(montab.color=='r');
            [row_g,col_g] = find(montab.color=='g');
            if ~isempty(row_r)
                r = uistyle('BackgroundColor','r');
                addStyle(app.MonTbl,r,'cell',[row_r,col_r+1]);
            end
            if ~isempty(row_g)
                g = uistyle('BackgroundColor','g');
                addStyle(app.MonTbl,g,'cell',[row_g,col_g+1]);
            end
            query = sprintf('SELECT faculty.fullname FROM faculty where faculty.fullname NOT IN (SELECT DISTINCT faculty.fullname FROM faculty RIGHT JOIN attendance ON faculty.uid=attendance.cardid);');
            absentlist = fetch(app.SqlObj, query);
            blankcol = strings(height(absentlist), 1);
            absentlist.txt = blankcol;
            app.MonTbl.Data = [montab(:,[2 5]); absentlist(:,:)];
        end
        
        function resetapp(app)
            try
                JavaTcpServer(app, 'close',app.TCP);
            end
            try
                delete(app.SerialObj)
            end
            try
                close(app.SqlObj)
            end
        end

        function iscomptab(app, idx, info)
            app.TabGroup.Visible = false;
            idval = app.T{idx(1), 1};
            fig = uifigure('Position',[408 285 384 190]);
            fig.CloseRequestFcn = @(src,event)my_closereq(src);
            confpressed = false;
            if info == true
                message = 'Mark as Completed?';
            else
                message = 'Marking a record as incomplete requires new schedule assignment. Continue?';
            end
            selection = uiconfirm(fig, message, 'Confirm', ...
                'Options', {'Confirm', 'Cancel'}, ...
                'CancelOption', 2, ...
                'Icon', 'question', ...
                'CloseFcn', @(src, event)my_closereq(src));
            switch selection
                case 'Confirm'
                    confpressed = true;
                    if info == true
                        % Delete sched record
                        if strcmp(app.T{idx(1), 7}, 'Consultation Room') == 1
                            query = sprintf('DELETE FROM sched WHERE parent_id = ''%d'';', app.T{idx(1), 1});
                            execute(app.SqlObj, query);
                        end
                    else
                        % Make new record
                        query = sprintf('UPDATE studreq SET fin_date = ''TBA'', fin_time_start = ''TBA'', fin_time_end = '' '' WHERE id = ''%d'';',app.schedreq.id(1));
                        execute(app.SqlObj, query);
                        query = sprintf('SELECT id, stud_name, room, prop_date, prop_time_start, prop_time_end, fac FROM studreq WHERE id = ''%d'';', app.T.id(idx(1), :));
                        app.schedreq = fetch(app.SqlObj, query);
                        app.QueueTbl.Selection = [];
                        app.TabGroup.SelectedTab = app.ScheduleTab;
                        app.PropDateLbl.Text = datestr(app.schedreq.prop_date{:}, 'mmmm dd, yyyy');
                        app.PropSchLbl.Text = [datestr(datetime(app.schedreq.prop_time_start{:}, 'InputFormat', 'HH:mm'), 'HH:MM PM') ' to ' datestr(datetime(app.schedreq.prop_time_end{:}, 'InputFormat', 'HH:mm'), 'HH:MM PM')];
                    end
                    query = strcat('UPDATE studreq SET isdone=', num2str(info), ' WHERE id=', num2str(idval), ';');
                    execute(app.SqlObj,query);
                case 'Cancel'
                    confpressed = false;
                    return;
            end
            app.TabGroup.Visible = true;
            showtab(app, app.SqlObj)
            function my_closereq(fig)
                if confpressed == true
                    delete(fig)
                    app.TabGroup.Visible = true;
                else
                    showtab(app, app.SqlObj)
                    delete(fig)
                    app.TabGroup.Visible = true;
                end
            end
        end

        function addsched(app, idx)
            app.TabGroup.Visible = false;
            fig = uifigure('Position',[408 285 384 190]);
            fig.CloseRequestFcn = @(src,event)my_closereq(src);
            confpressed = false;
            try
                if strcmp(app.T.fin_sched{idx(1)},'TBA  ') || strcmp(app.T.fin_sched{idx(1)},'TBA')
                    message = 'Assign schedule to entry?';
                else
                    message = 'Editing an entry requires new schedule assignment. Continue?';
                end
                selection = uiconfirm(fig, message, 'Confirm', ...
                    'Options', {'Confirm', 'Cancel'}, ...
                    'CancelOption', 2, ...
                    'Icon', 'question', ...
                    'CloseFcn', @(src, event)my_closereq(src));
                switch selection
                    case 'Confirm'
                        confpressed = true;
                        query = sprintf('SELECT id, stud_name, room, prop_date, prop_time_start, prop_time_end, fac FROM studreq WHERE id = ''%d'';', app.T.id(idx(1), :));
                        app.schedreq = fetch(app.SqlObj, query);
                        if strcmp(message, 'Assign schedule to entry?') == 0
                            query = sprintf('UPDATE studreq SET fin_date = ''TBA'', fin_time_start = ''TBA'', fin_time_end = '' '' WHERE id = ''%d'';',app.schedreq.id(1));
                            execute(app.SqlObj, query);
                            if strcmp(app.schedreq.room{1}, 'Consultation Room') == 1
                                query = sprintf('DELETE FROM sched WHERE parent_id = ''%d'';', app.schedreq.id(1));
                                execute(app.SqlObj, query);
                            end
                        end
                        app.QueueTbl.Selection = [];
                        app.TabGroup.SelectedTab = app.ScheduleTab;
                        app.PropDateLbl.Text = datestr(app.schedreq.prop_date{:}, 'mmmm dd, yyyy');
                        app.PropSchLbl.Text = [datestr(datetime(app.schedreq.prop_time_start{:}, 'InputFormat', 'HH:mm'), 'HH:MM PM') ' to ' datestr(datetime(app.schedreq.prop_time_end{:}, 'InputFormat', 'HH:mm'), 'HH:MM PM')];
                    case 'Cancel'
                        confpressed = false;
                        app.QueueTbl.Selection = [];
                        return;
                end
                app.TabGroup.Visible = true;
            end
            function my_closereq(fig)
                if confpressed == true
                    delete(fig)
                    app.TabGroup.Visible = true;
                else
                    showtab(app, app.SqlObj)
                    delete(fig)
                    app.TabGroup.Visible = true;
                end
            end
        end
        
        function assign(app)
            findate = datestr(app.DatePicker.Value, 'mmmm dd, yyyy');
            findatesql = datestr(app.DatePicker.Value, 'yyyy-mm-dd');
            fintimestart = datetime([num2str(app.StartHrSpn.Value) ':' num2str(app.StartMinSpn.Value)], 'InputFormat', 'HH:mm');
            fintimestartstr = datestr(fintimestart, 'HH:MM AM');
            fintimestartsql = datestr(fintimestart, 'HH:MM');
            fintimeend = datetime([num2str(app.EndHrSpn.Value) ':' num2str(app.EndMinSpn.Value)], 'InputFormat', 'HH:mm');
            fintimeendstr = datestr(fintimeend, 'HH:MM AM');
            fintimeendsql = datestr(fintimeend, 'HH:MM');
            if fintimestart >= fintimeend
                fig = uifigure('Position',[408 285 384 190]);
                fig.CloseRequestFcn = @(src,event)my_closereq(src);
                uialert(fig,'Please enter valid date and time values.','Error',...
                    'Icon','error', ...
                    'modal', true, ...
                    'CloseFcn', @(src, event)my_closereq(src));
                return
            end
            %% Check room
            query = sprintf('SELECT * FROM sched WHERE datesch = ''%s'';', findatesql);
            if strcmp(app.RoomEF.Value, 'Consultation Room') == 1
                roomdata = fetch(app.SqlObj, query);
                if ~isempty(roomdata)
                    tf = [fintimestart fintimeend];
                    for i = 1:height(roomdata)
                        tlow = datetime(roomdata.startsch{i}, 'inputformat', 'HH:mm', 'format', 'HH:mm');
                        thigh = datetime(roomdata.endsch{i}, 'inputformat', 'HH:mm', 'format', 'HH:mm');
                        A = isbetween(tf(1), tlow, thigh);
                        B = isbetween(tf(2), tlow, thigh);
                        C = tf(1) < tlow;
                        D = tf(2) > thigh;
                        %check time availability
                        if ~(~A && ~C || ~B && ~D)
                            fig = uifigure('Position',[408 285 384 190]);
                            fig.CloseRequestFcn = @(src,event)my_closereq(src);
                            uialert(fig,'Please pick another timeslot.','Timeslot Unavailable',...
                                'Icon','error', ...
                                'modal', true, ...
                                'CloseFcn', @(src, event)my_closereq(src));
                            app.DatePicker.Value = datetime(now(), 'ConvertFrom', 'datenum', 'Format', 'dd-MMM-yyyy');
                            app.StartHrSpn.Value = 7;
                            app.StartMinSpn.Value = 30;
                            app.EndHrSpn.Value = 7;
                            app.EndMinSpn.Value = 30;
                            return
                        end
                    end
                end
            end 
            
            %% Room Available
            message = app.MessageTextArea.Value{:};
            app.TabGroup.Visible = false;
            if strcmp(message, '')
                msg = sprintf('Date: %s\nSchedule: %s to %s\nRoom: %s', findate, fintimestartstr, fintimeendstr, app.RoomEF.Value);
            else
                msg = sprintf('Date: %s\nSchedule: %s to %s\nRoom: %s\nMessage: %s', findate, fintimestartstr, fintimeendstr, app.RoomEF.Value, message);
            end
            fig = uifigure('Position',[408 285 384 190]);
            fig.CloseRequestFcn = @(src,event)my_closereq(src);
            selection = uiconfirm(fig, msg, 'Finalize Meeting?', ...
                'Options', {'OK', 'Cancel'}, ...
                'CancelOption', 2, ...
                'Icon', 'question', ...
                'CloseFcn', @(src, event)my_closereq(src));
            switch selection
                case 'OK'
                    query = sprintf('UPDATE studreq SET fin_date = ''%s'', fin_time_start = ''%s'', fin_time_end = ''%s'', message = ''%s'', room = ''%s'' WHERE id = ''%d'';', ...
                        findatesql, fintimestartsql, fintimeendsql, message, app.RoomEF.Value, app.schedreq.id);
                    execute(app.SqlObj,query);
                    query = sprintf('INSERT INTO sched (parent_id ,datesch, startsch, endsch, faculty) VALUES (%d, ''%s'', ''%s'', ''%s'', ''%s'');', ...
                        app.schedreq.id(1), findatesql, fintimestartsql, fintimeendsql, app.schedreq.fac{:});
                    execute(app.SqlObj,query);
                    emailsend(app, app.SqlObj, app.schedreq.id)
                    % reset
                    app.RoomEF.Value = 'Consultation Room';
                    app.DatePicker.Value = datetime(now(), 'ConvertFrom', 'datenum', 'Format', 'dd-MMM-yyyy');
                    app.StartHrSpn.Value = 7;
                    app.StartMinSpn.Value = 30;
                    app.EndHrSpn.Value = 7;
                    app.EndMinSpn.Value = 30;
                    app.MessageTextArea.Value = '';
                    app.schedreq = table();
                    app.TabGroup.SelectedTab = app.ConsultationTab;
                    showtab(app, app.SqlObj);
                    fig = uifigure('Position',[408 285 384 190]);
                    fig.CloseRequestFcn = @(src,event)my_closereq(src);
                    uialert(fig,'Meeting finalized.','Operation Success',...
                        'Icon','success', ...
                        'modal', true, ...
                        'CloseFcn', @(src, event)my_closereq(src));
                case 'Cancel'
                    return
            end
            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
            end
        end
        
        function showtab(app, obj, facname)
            % Refresh Daily
            if nargin <=2
                query0 = char("SELECT id, datestamp, timestamp, fac, purpose, stud_name,  room, fin_date, CONCAT(fin_time_start, ' ', fin_time_end) as 'fin_sched', isdone FROM studreq WHERE isdone=0 ORDER BY fac ASC, id ASC;");
                query1 = char("SELECT id, datestamp, timestamp, fac, purpose, stud_name,  room, fin_date, CONCAT(fin_time_start, ' ', fin_time_end) as 'fin_sched', isdone FROM studreq WHERE isdone=1 ORDER BY fac ASC, id ASC;");
            else
                query0 = sprintf('SELECT id, datestamp, timestamp, fac, purpose, stud_name,  room, fin_date, CONCAT(fin_time_start, '' '', fin_time_end) as ''fin_sched'', isdone FROM studreq WHERE isdone=0 AND fac=''%s'' ORDER BY fac ASC, id ASC;', facname);
                query1 = sprintf('SELECT id, datestamp, timestamp, fac, purpose, stud_name,  room, fin_date, CONCAT(fin_time_start, '' '', fin_time_end) as ''fin_sched'', isdone FROM studreq WHERE isdone=1 AND fac=''%s'' ORDER BY fac ASC, id ASC;', facname);

            end
            T0 = fetch(obj, query0);      
            T1 = fetch(obj, query1);
            app.T = [T0; T1];
            app.T = convertvars(app.T,'isdone','logical');
            app.QueueTbl.Data = app.T(:,2:end);
            for i = 1:height(app.QueueTbl.Data)
                if ~strcmp(app.QueueTbl.Data.fin_sched{i}, 'TBA  ')
                    app.QueueTbl.Data.fin_sched{i} = [app.QueueTbl.Data.fin_sched{i}(1:5) ' to ' app.QueueTbl.Data.fin_sched{i}(7:end)];
                end
            end
            %Edit Dropdown Refresh
            refreshdd(app)
        end

        function refreshdd(app)
            query = sprintf('SELECT fullname FROM faculty;');
            data = fetch(app.SqlObj, query);
            app.FilterDD.Items = [{'Select to Filter Entries'} unique(data.fullname')];
            app.FilterDD.Value = 'Select to Filter Entries';
        end
    
        function showaddlab(app)
            [~, result] = system('ipconfig');
            a = split(result);  
            a = string(a);
            id = find(strcmp(a(:),'IPv4') == 1);
            ipstr = a(id+13);

            app.WebAddLbl.Text = strcat(ipstr,':1029');
            if ~isempty(app.WebAddLbl.Text)
                app.WebSrvLbl.Visible = "on";
                app.WebAddLbl.Visible = "on";
            else
                app.WebAddLbl.Text = 'Web Server Unavailable';
            end
        end
    
        function excelexport(app,mode)
            filter = {'*.xlsx';'*.xlx';};
            [file, path] = uiputfile(filter, 'Choose Save Location',datestr(now(), 'yyyy-mm-dd'));
            if file == 0, return; end
            switch mode
                case 'fac'
                    query = sprintf('SELECT * FROM attendance');
                    data = fetch(app.SqlObj, query);
                    writetable(data,[path file],'Sheet','Faculty Attendance');
                case 'stud'
                    query = sprintf('SELECT * FROM studreq');
                    data = fetch(app.SqlObj, query);
                    writetable(data,[path file],'Sheet','Student Requests');
                case 'room'
                    query = sprintf('SELECT * FROM sched');
                    data = fetch(app.SqlObj, query);
                    writetable(data,[path file],'Sheet','Room Assignments');
                case 'all'
                    query1 = sprintf('SELECT * FROM attendance');
                    query2 = sprintf('SELECT * FROM studreq');
                    query3 = sprintf('SELECT * FROM sched');
                    data1 = fetch(app.SqlObj, query1);
                    data2 = fetch(app.SqlObj, query2);
                    data3 = fetch(app.SqlObj, query3);
                    writetable(data1,[path file],'Sheet','Faculty Attendance');
                    writetable(data2,[path file],'Sheet','Student Requests');
                    writetable(data3,[path file],'Sheet','Room Assignments');
            end
            fig = uifigure('Position',[408 285 384 190]);
            fig.CloseRequestFcn = @(src,event)my_closereq(src);
            uialert(fig,'Data Exported.','Operation Success',...
                'Icon','success', ...
                'modal', true, ...
                'CloseFcn', @(src, event)my_closereq(src));
            function my_closereq(fig)
                delete(fig)
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','all')          
            setupapp(app, 0.3)
            try
                showaddlab(app)
                showtab(app, app.SqlObj)
                showatt(app)
                tap(app)
            end
            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
            end
        end

        % Cell selection callback: QueueTbl
        function QueueTblCellSelection(app, event)
            indices = event.Indices;
            %% Room Assignment app
            try
                if indices(1) > height(app.T)
                    return
                end
                if indices(2) < 6 || indices(2) == 9
                    return
                end
                addsched(app, indices);
            end
            app.QueueTbl.Selection = [];
        end

        % Cell edit callback: QueueTbl
        function QueueTblCellEdit(app, event)
            iscomptab(app, event.Indices,event.NewData)
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            app.QueueTbl.Data = table();
            pause(0.05)
            if strcmp(app.FilterDD.Value,'Select to Filter Entries')
                showtab(app, app.SqlObj)
            else
                showtab(app, app.SqlObj, app.FilterDD.Value)
            end
        end

        % Value changed function: FilterDD
        function FilterDDValueChanged(app, event)
            if strcmp(app.FilterDD.Value,'Select to Filter Entries')
                showtab(app, app.SqlObj)
            else
                showtab(app, app.SqlObj, app.FilterDD.Value)
            end
        end

        % Button pushed function: RegBtn
        function RegBtnButtonPushed(app, event)
            reg(app, app.FNameEFld.Value, app.MNameEFld.Value, app.LNameEFld.Value, app.EmailEFld.Value)
            showtab(app, app.SqlObj)
        end

        % Button pushed function: DelBtn
        function DelBtnButtonPushed(app, event)
            del(app)
            showtab(app, app.SqlObj)
        end

        % Button down function: ConsultationTab
        function ConsultationTabButtonDown(app, event)
            resetapp(app)
            setupapp(app, 0.3)
            try
                showtab(app, app.SqlObj)
                tap(app)
            end
            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
            end
        end

        % Button down function: ScheduleTab
        function ScheduleTabButtonDown(app, event)
            app.TabGroup.Visible = false;
            fig = uifigure('Position',[408 285 384 190]);
            fig.CloseRequestFcn = @(src,event)my_closereq(src);
            uialert(fig, 'Please select an entry from the Consultation Tab.', 'Press OK', ...
                'Icon', 'warning', ...
                'CloseFcn', @(src, event)my_closereq(src));
            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
                app.TabGroup.SelectedTab = app.ConsultationTab;
            end
        end

        % Button down function: RegistrationTab
        function RegistrationTabButtonDown(app, event)
            resetapp(app)
            setupapp(app, 5)
            try
                showtab(app, app.SqlObj)
                tap(app)
            end
            function my_closereq(fig)
                delete(fig)
                app.TabGroup.Visible = true;
            end
        end

        % Button pushed function: UseProposedButton
        function UseProposedButtonPushed(app, event)
            try
                app.DatePicker.Value = datetime(app.schedreq.prop_date{:});
            end
            app.StartHrSpn.Value = hour(datetime(app.schedreq.prop_time_start{:}, 'InputFormat', 'HH:mm'));
            app.StartMinSpn.Value = minute(datetime(app.schedreq.prop_time_start{:}, 'InputFormat', 'HH:mm'));
            app.EndHrSpn.Value = hour(datetime(app.schedreq.prop_time_end{:}, 'InputFormat', 'HH:mm'));
            app.EndMinSpn.Value = minute(datetime(app.schedreq.prop_time_end{:}, 'InputFormat', 'HH:mm'));
        end

        % Button pushed function: AssgnBtn
        function AssgnBtnPushed(app, event)
            assign(app);
        end

        % Close request function: FAMOSv100UIFigure
        function FAMOSv100UIFigureCloseRequest(app, event)
            resetapp(app);
            delete(app);
        end

        % Menu selected function: FacultyAttendanceMenu
        function FacultyAttendanceMenuSelected(app, event)
            excelexport(app, 'fac');
        end

        % Menu selected function: StudentRequestsMenu
        function StudentRequestsMenuSelected(app, event)
            excelexport(app, 'stud');
        end

        % Menu selected function: RoomScheduleMenu
        function RoomScheduleMenuSelected(app, event)
            excelexport(app, 'room');
        end

        % Menu selected function: AllMenu
        function AllMenuSelected(app, event)
            excelexport(app, 'all');
        end

        % Button down function: MonitoringTab
        function MonitoringTabButtonDown(app, event)
            showatt(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FAMOSv100UIFigure = uifigure('Visible', 'off');
            app.FAMOSv100UIFigure.Name = 'FAMOS v1.0.0';
            app.FAMOSv100UIFigure.Icon = fullfile(pathToMLAPP, 'Mapua Logo.png');
            app.FAMOSv100UIFigure.CloseRequestFcn = createCallbackFcn(app, @FAMOSv100UIFigureCloseRequest, true);
            app.ExportMenu = uimenu(app.FAMOSv100UIFigure);
            app.ExportMenu.Text = 'Export';
            app.FacultyAttendanceMenu = uimenu(app.ExportMenu);
            app.FacultyAttendanceMenu.MenuSelectedFcn = createCallbackFcn(app, @FacultyAttendanceMenuSelected, true);
            app.FacultyAttendanceMenu.Text = 'Faculty Attendance';
            app.StudentRequestsMenu = uimenu(app.ExportMenu);
            app.StudentRequestsMenu.MenuSelectedFcn = createCallbackFcn(app, @StudentRequestsMenuSelected, true);
            app.StudentRequestsMenu.Text = 'Student Requests';
            app.RoomScheduleMenu = uimenu(app.ExportMenu);
            app.RoomScheduleMenu.MenuSelectedFcn = createCallbackFcn(app, @RoomScheduleMenuSelected, true);
            app.RoomScheduleMenu.Text = 'Room Schedule';
            app.AllMenu = uimenu(app.ExportMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @AllMenuSelected, true);
            app.AllMenu.Text = 'All';
            app.Menu = uimenu(app.FAMOSv100UIFigure);
            app.TabGroup = uitabgroup(app.FAMOSv100UIFigure);
            app.ConsultationTab = uitab(app.TabGroup);
            app.ConsultationTab.Title = 'Consultation';
            app.ConsultationTab.ButtonDownFcn = createCallbackFcn(app, @ConsultationTabButtonDown, true);
            app.GridLayout = uigridlayout(app.ConsultationTab);
            app.QueueTbl = uitable(app.GridLayout);
            app.QueueTbl.ColumnName = {'Date Created'; 'Time Created'; 'Faculty'; 'Purpose'; 'Student Name'; 'Room'; 'Meet Date'; 'Schedule'; 'Completed'};
            app.QueueTbl.RowName = {};
            app.QueueTbl.ColumnEditable = [false false false false false false false true true];
            app.QueueTbl.CellEditCallback = createCallbackFcn(app, @QueueTblCellEdit, true);
            app.QueueTbl.CellSelectionCallback = createCallbackFcn(app, @QueueTblCellSelection, true);
            app.QueueTbl.Multiselect = 'off';
            app.WebAddLbl = uilabel(app.GridLayout);
            app.WebAddLbl.Text = '';
            app.WebSrvLbl = uilabel(app.GridLayout);
            app.WebSrvLbl.Visible = 'off';
            app.WebSrvLbl.Text = 'Server Address:';
            app.FilterDD = uidropdown(app.GridLayout);
            app.FilterDD.ValueChangedFcn = createCallbackFcn(app, @FilterDDValueChanged, true);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Mapua Logo.png');
            app.MonitoringTab = uitab(app.TabGroup);
            app.MonitoringTab.Title = 'Monitoring';
            app.MonitoringTab.ButtonDownFcn = createCallbackFcn(app, @MonitoringTabButtonDown, true);
            app.MonTbl = uitable(app.MonitoringTab);
            app.MonTbl.ColumnName = {'Faculty'; 'Status'};
            app.MonTbl.RowName = {};
            app.MonTbl.SelectionType = 'row';
            app.MonTbl.Multiselect = 'off';
            app.LegendPanel = uipanel(app.MonitoringTab);
            app.LegendPanel.Title = 'Legend';
            app.EnteredOfficeLampLabel = uilabel(app.LegendPanel);
            app.EnteredOfficeLampLabel.Text = 'Entered Office';
            app.EnteredOfficeLamp = uilamp(app.LegendPanel);
            app.LeftOfficeLampLabel = uilabel(app.LegendPanel);
            app.LeftOfficeLampLabel.Text = 'Left Office';
            app.LeftOfficeLamp = uilamp(app.LegendPanel);
            app.AbsentLampLabel = uilabel(app.LegendPanel);
            app.AbsentLampLabel.Text = 'Absent';
            app.AbsentLamp = uilamp(app.LegendPanel);
            app.ScheduleTab = uitab(app.TabGroup);
            app.ScheduleTab.Title = 'Schedule';
            app.ScheduleTab.ButtonDownFcn = createCallbackFcn(app, @ScheduleTabButtonDown, true);
            app.Panel_3 = uipanel(app.ScheduleTab);
            app.Panel = uipanel(app.Panel_3);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.ProposedDateLabel = uilabel(app.GridLayout4);
            app.ProposedDateLabel.Text = 'Proposed Date:';
            app.PropDateLbl = uilabel(app.GridLayout4);
            app.PropDateLbl.Text = '';
            app.ProposedTimeLabel = uilabel(app.GridLayout4);
            app.ProposedTimeLabel.Text = 'Proposed Time:';
            app.PropSchLbl = uilabel(app.GridLayout4);
            app.PropSchLbl.Text = '';
            app.Panel_2 = uipanel(app.Panel_3);
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = ':';
            app.Label_2 = uilabel(app.GridLayout5);
            app.Label_2.Text = ':';
            app.DateLabel = uilabel(app.GridLayout5);
            app.DateLabel.Text = 'Date:';
            app.DatePicker = uidatepicker(app.GridLayout5);
            app.DatePicker.Limits = [datetime([0 1 7]) datetime([9999 12 31])];
            app.DatePicker.Value = datetime([2022 9 13]);
            app.StartTimeLabel = uilabel(app.GridLayout5);
            app.StartTimeLabel.Text = 'Start Time:';
            app.StartHrSpn = uispinner(app.GridLayout5);
            app.StartHrSpn.Limits = [7 20];
            app.StartHrSpn.RoundFractionalValues = 'on';
            app.StartHrSpn.ValueDisplayFormat = '%02d';
            app.StartHrSpn.Value = 7;
            app.StartMinSpn = uispinner(app.GridLayout5);
            app.StartMinSpn.Limits = [0 59];
            app.StartMinSpn.RoundFractionalValues = 'on';
            app.StartMinSpn.ValueDisplayFormat = '%02d';
            app.StartMinSpn.Value = 30;
            app.EndTimeSpinnerLabel = uilabel(app.GridLayout5);
            app.EndTimeSpinnerLabel.Text = 'End Time:';
            app.EndHrSpn = uispinner(app.GridLayout5);
            app.EndHrSpn.Limits = [7 20];
            app.EndHrSpn.RoundFractionalValues = 'on';
            app.EndHrSpn.ValueDisplayFormat = '%02d';
            app.EndHrSpn.Value = 7;
            app.EndMinSpn = uispinner(app.GridLayout5);
            app.EndMinSpn.Limits = [0 59];
            app.EndMinSpn.RoundFractionalValues = 'on';
            app.EndMinSpn.ValueDisplayFormat = '%02d';
            app.EndMinSpn.Value = 30;
            app.RoomEditFieldLabel = uilabel(app.GridLayout5);
            app.RoomEditFieldLabel.Text = 'Room:';
            app.RoomEF = uieditfield(app.GridLayout5, 'text');
            app.RoomEF.Value = 'Consultation Room';
            app.MessageTextAreaLabel = uilabel(app.Panel_3);
            app.MessageTextAreaLabel.Text = 'Message:';
            app.OptionalLabel = uilabel(app.Panel_3);
            app.OptionalLabel.Text = '(Optional)';
            app.UseProposedButton = uibutton(app.Panel_3, 'push');
            app.UseProposedButton.ButtonPushedFcn = createCallbackFcn(app, @UseProposedButtonPushed, true);
            app.UseProposedButton.Text = 'Use Proposed';
            app.MessageTextArea = uitextarea(app.Panel_3);
            app.AssgnBtn = uibutton(app.Panel_3, 'push');
            app.AssgnBtn.ButtonPushedFcn = createCallbackFcn(app, @AssgnBtnPushed, true);
            app.AssgnBtn.Text = 'Assign';
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.RegistrationTab.ButtonDownFcn = createCallbackFcn(app, @RegistrationTabButtonDown, true);
            app.GridLayout3 = uigridlayout(app.RegistrationTab);
            app.mapuaeduphLabel = uilabel(app.GridLayout3);
            app.mapuaeduphLabel.Text = '@mapua.edu.ph';
            app.NameLabel_4 = uilabel(app.GridLayout3);
            app.NameLabel_4.Text = 'First Name: ';
            app.FNameEFld = uieditfield(app.GridLayout3, 'text');
            app.FNameEFld.Tooltip = {''};
            app.NameLabel_5 = uilabel(app.GridLayout3);
            app.NameLabel_5.Text = 'Middle Initial:';
            app.MNameEFld = uieditfield(app.GridLayout3, 'text');
            app.MNameEFld.Tooltip = {''};
            app.NameLabel_6 = uilabel(app.GridLayout3);
            app.NameLabel_6.Text = 'Last Name: ';
            app.LNameEFld = uieditfield(app.GridLayout3, 'text');
            app.LNameEFld.Tooltip = {''};
            app.EmailLabel = uilabel(app.GridLayout3);
            app.EmailLabel.Text = 'Email: ';
            app.EmailEFld = uieditfield(app.GridLayout3, 'text');
            app.RegBtn = uibutton(app.GridLayout3, 'push');
            app.RegBtn.ButtonPushedFcn = createCallbackFcn(app, @RegBtnButtonPushed, true);
            app.RegBtn.Text = 'Register';
            app.DelBtn = uibutton(app.GridLayout3, 'push');
            app.DelBtn.ButtonPushedFcn = createCallbackFcn(app, @DelBtnButtonPushed, true);
            app.DelBtn.Text = 'Delete';
            app.ContextMenu = uicontextmenu(app.FAMOSv100UIFigure);
            app.RefreshMenu = uimenu(app.ContextMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            % Assign app.ContextMenu
            app.QueueTbl.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.FAMOSv100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sample

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FAMOSv100UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FAMOSv100UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FAMOSv100UIFigure)
        end
    end
end